classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CoordinatescalculatorformultipleexperiencesUIFigure  matlab.ui.Figure
        SelectedfoldersTextAreaLabel  matlab.ui.control.Label
        SelectedfoldersTextArea       matlab.ui.control.TextArea
        CoordinatescalculatorformultipleexperiencesLabel  matlab.ui.control.Label
        SelectanewfolderButton        matlab.ui.control.StateButton
        RemovethelastentryButton      matlab.ui.control.StateButton
        Label                         matlab.ui.control.Label
        Lamp                          matlab.ui.control.Lamp
        LaunchcalculusButton          matlab.ui.control.StateButton
        CalibrationimagesdirectoryEditFieldLabel  matlab.ui.control.Label
        CalibrationimagesdirectoryEditField  matlab.ui.control.EditField
        BrowseButton                  matlab.ui.control.StateButton
        MaxspeedmsEditFieldLabel      matlab.ui.control.Label
        MaxspeedmsEditField           matlab.ui.control.NumericEditField
        FrameRatefpsEditFieldLabel    matlab.ui.control.Label
        FrameRatefpsEditField         matlab.ui.control.NumericEditField
        SquaresizeofCheckerboardmmEditFieldLabel  matlab.ui.control.Label
        SquaresizeofCheckerboardmmEditField  matlab.ui.control.NumericEditField
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Value changed function: BrowseButton
        function BrowseButtonValueChanged(app, event)
            folder_name = uigetdir;
            app.CalibrationimagesdirectoryEditField.Value = folder_name;
        end

        % Value changed function: SelectanewfolderButton
        function SelectanewfolderButtonValueChanged(app, event)
            folder_name = uigetdir;
            if length(app.SelectedfoldersTextArea.Value{1})==0
                app.SelectedfoldersTextArea.Value{end} = folder_name;
            else
                app.SelectedfoldersTextArea.Value{end+1} = folder_name;
            end
        end

        % Value changed function: RemovethelastentryButton
        function RemovethelastentryButtonValueChanged(app, event)
            if length(app.SelectedfoldersTextArea.Value)==1
                app.SelectedfoldersTextArea.Value = {''};
            else
                app.SelectedfoldersTextArea.Value = app.SelectedfoldersTextArea.Value{1:end-1};
            end
        end

        % Value changed function: LaunchcalculusButton
        function LaunchcalculusButtonValueChanged(app, event)
            app.Lamp.Color = [1.00, 1.00, 0.00];
            main(app.SelectedfoldersTextArea.Value, app.CalibrationimagesdirectoryEditField.Value, app.MaxspeedmsEditField.Value, app.FrameRatefpsEditField.Value, app.SquaresizeofCheckerboardmmEditField.Value);
            app.Lamp.Color = [0.00, 1.00, 0.00];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CoordinatescalculatorformultipleexperiencesUIFigure = uifigure;
            app.CoordinatescalculatorformultipleexperiencesUIFigure.Name = 'Coordinates calculator for multiple experiences';
            app.SelectedfoldersTextAreaLabel = uilabel(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.SelectedfoldersTextAreaLabel.Text = 'Selected folders';
            app.SelectedfoldersTextArea = uitextarea(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.SelectedfoldersTextArea.Editable = 'off';
            app.CoordinatescalculatorformultipleexperiencesLabel = uilabel(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.CoordinatescalculatorformultipleexperiencesLabel.Text = 'Coordinates calculator for multiple experiences';
            app.SelectanewfolderButton = uibutton(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'state');
            app.SelectanewfolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectanewfolderButtonValueChanged, true);
            app.SelectanewfolderButton.Text = 'Select a new folder';
            app.RemovethelastentryButton = uibutton(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'state');
            app.RemovethelastentryButton.ValueChangedFcn = createCallbackFcn(app, @RemovethelastentryButtonValueChanged, true);
            app.RemovethelastentryButton.Text = 'Remove the last entry';
            app.Label = uilabel(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.Label.Text = '';
            app.Lamp = uilamp(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.LaunchcalculusButton = uibutton(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'state');
            app.LaunchcalculusButton.ValueChangedFcn = createCallbackFcn(app, @LaunchcalculusButtonValueChanged, true);
            app.LaunchcalculusButton.Text = 'Launch calculus';
            app.CalibrationimagesdirectoryEditFieldLabel = uilabel(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.CalibrationimagesdirectoryEditFieldLabel.Text = 'Calibration images directory';
            app.CalibrationimagesdirectoryEditField = uieditfield(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'text');
            app.BrowseButton = uibutton(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'state');
            app.BrowseButton.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonValueChanged, true);
            app.BrowseButton.Text = 'Browse';
            app.MaxspeedmsEditFieldLabel = uilabel(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.MaxspeedmsEditFieldLabel.Text = 'Max speed (m/s)';
            app.MaxspeedmsEditField = uieditfield(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'numeric');
            app.MaxspeedmsEditField.Limits = [0 Inf];
            app.MaxspeedmsEditField.Value = 4;
            app.FrameRatefpsEditFieldLabel = uilabel(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.FrameRatefpsEditFieldLabel.Text = 'Frame Rate (fps)';
            app.FrameRatefpsEditField = uieditfield(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'numeric');
            app.FrameRatefpsEditField.Limits = [0 Inf];
            app.FrameRatefpsEditField.Value = 100;
            app.SquaresizeofCheckerboardmmEditFieldLabel = uilabel(app.CoordinatescalculatorformultipleexperiencesUIFigure);
            app.SquaresizeofCheckerboardmmEditFieldLabel.Text = 'Square size of Checkerboard (mm)';
            app.SquaresizeofCheckerboardmmEditField = uieditfield(app.CoordinatescalculatorformultipleexperiencesUIFigure, 'numeric');
            app.SquaresizeofCheckerboardmmEditField.Limits = [0 Inf];
            app.SquaresizeofCheckerboardmmEditField.Value = 30;
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CoordinatescalculatorformultipleexperiencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CoordinatescalculatorformultipleexperiencesUIFigure)
        end
    end
end