classdef guiAT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrajectoriesanalysesUIFigure    matlab.ui.Figure
        SelectanewexperienceButtonGroup  matlab.ui.container.ButtonGroup
        CCButton                        matlab.ui.control.RadioButton
        MBButton                        matlab.ui.control.RadioButton
        MHButton                        matlab.ui.control.RadioButton
        MMButton                        matlab.ui.control.RadioButton
        DirectoryLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        AddButton                       matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        TypeLabel                       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        TrajectoriesanalysesLabel       matlab.ui.control.Label
        RemovethelastentryButton        matlab.ui.control.Button
        LaunchcalculusButton            matlab.ui.control.Button
        SampleTimefpsEditFieldLabel     matlab.ui.control.Label
        SampleTimefpsEditField          matlab.ui.control.NumericEditField
        TunnelHeightmEditFieldLabel     matlab.ui.control.Label
        TunnelHeightmEditField          matlab.ui.control.NumericEditField
        FloorAltitudemEditFieldLabel    matlab.ui.control.Label
        FloorAltitudemEditField         matlab.ui.control.NumericEditField
        LateralPositionmEditFieldLabel  matlab.ui.control.Label
        LateralPositionmEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.UITable.Data = [app.UITable.Data; {app.EditField.Value, app.SelectanewexperienceButtonGroup.SelectedObject.Text}];
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.EditField.Value = uigetdir;
        end

        % Button pushed function: RemovethelastentryButton
        function RemovethelastentryButtonPushed(app, event)
            if length(app.UITable.Data)==1
                app.UITable.Data = {};
            else
                app.UITable.Data = [app.UITable.Data(1:end-1,:)];
            end
        end

        % Button pushed function: LaunchcalculusButton
        function LaunchcalculusButtonPushed(app, event)
            for i = 1:length(app.UITable.Data(:,1))
                dir = app.UITable.Data(i,1);
                dir = dir{1};
                experienceType = app.UITable.Data(i,2);
                experienceType = experienceType{1};
                calculateOFVD = 1;
                tunnelHeight = app.TunnelHeightmEditField.Value;
                floorAltitude = app.FloorAltitudemEditField.Value;
                if(experienceType == 'MH')
                    tunnelHeight = tunnelHeight*2;
                elseif(experienceType == 'MB')
                    floorAltitude = floorAltitude - tunnelHeight;
                    tunnelHeight = tunnelHeight*2;
                elseif(experienceType == 'MM')
                    calculateOFVD = 0;
                end
                
                insect = othersParametersCalculus(sprintf('%s\\coordinates.mat', dir), calculateOFVD, app.SampleTimefpsEditField.Value, tunnelHeight, floorAltitude, app.LateralPositionmEditField.Value);
                decomposedPath = split(dir, '\');
                insectNumber = decomposedPath(end);
                dir = '';
                for j = 1:length(decomposedPath)-2
                    dir = fullfile(dir, decomposedPath(j));
                end
                dir = dir{1};
                variableName = sprintf('%s_%s', experienceType, insectNumber{1});
                matFileName = sprintf('%s\\%s.mat', dir, variableName);
                
                S.(variableName) = insect;
                save(matFileName, '-struct', 'S')
%                 save(matFileName, 'insect');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrajectoriesanalysesUIFigure = uifigure;
            app.TrajectoriesanalysesUIFigure.Name = 'Trajectories analyses';
            app.SelectanewexperienceButtonGroup = uibuttongroup(app.TrajectoriesanalysesUIFigure);
            app.SelectanewexperienceButtonGroup.Title = 'Select a new experience';
            app.CCButton = uiradiobutton(app.SelectanewexperienceButtonGroup);
            app.CCButton.Text = 'CC';
            app.CCButton.Value = true;
            app.MBButton = uiradiobutton(app.SelectanewexperienceButtonGroup);
            app.MBButton.Text = 'MB';
            app.MHButton = uiradiobutton(app.SelectanewexperienceButtonGroup);
            app.MHButton.Text = 'MH';
            app.MMButton = uiradiobutton(app.SelectanewexperienceButtonGroup);
            app.MMButton.Text = 'MM';
            app.DirectoryLabel = uilabel(app.SelectanewexperienceButtonGroup);
            app.DirectoryLabel.Text = 'Directory';
            app.EditField = uieditfield(app.SelectanewexperienceButtonGroup, 'text');
            app.EditField.Editable = 'off';
            app.AddButton = uibutton(app.SelectanewexperienceButtonGroup, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.BrowseButton = uibutton(app.SelectanewexperienceButtonGroup, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TypeLabel = uilabel(app.SelectanewexperienceButtonGroup);
            app.TypeLabel.Text = 'Type';
            app.UITable = uitable(app.TrajectoriesanalysesUIFigure);
            app.UITable.ColumnName = {'Directory'; 'Experience Type'};
            app.UITable.RowName = {};
            app.TrajectoriesanalysesLabel = uilabel(app.TrajectoriesanalysesUIFigure);
            app.TrajectoriesanalysesLabel.Text = 'Trajectories analyses';
            app.RemovethelastentryButton = uibutton(app.TrajectoriesanalysesUIFigure, 'push');
            app.RemovethelastentryButton.ButtonPushedFcn = createCallbackFcn(app, @RemovethelastentryButtonPushed, true);
            app.RemovethelastentryButton.Text = 'Remove the last entry';
            app.LaunchcalculusButton = uibutton(app.TrajectoriesanalysesUIFigure, 'push');
            app.LaunchcalculusButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchcalculusButtonPushed, true);
            app.LaunchcalculusButton.Text = 'Launch calculus';
            app.SampleTimefpsEditFieldLabel = uilabel(app.TrajectoriesanalysesUIFigure);
            app.SampleTimefpsEditFieldLabel.Text = 'Sample Time (fps)';
            app.SampleTimefpsEditField = uieditfield(app.TrajectoriesanalysesUIFigure, 'numeric');
            app.SampleTimefpsEditField.Limits = [0 Inf];
            app.SampleTimefpsEditField.Value = 100;
            app.TunnelHeightmEditFieldLabel = uilabel(app.TrajectoriesanalysesUIFigure);
            app.TunnelHeightmEditFieldLabel.Text = 'Tunnel Height (m)';
            app.TunnelHeightmEditField = uieditfield(app.TrajectoriesanalysesUIFigure, 'numeric');
            app.TunnelHeightmEditField.Limits = [0 Inf];
            app.TunnelHeightmEditField.Value = 0.75;
            app.FloorAltitudemEditFieldLabel = uilabel(app.TrajectoriesanalysesUIFigure);
            app.FloorAltitudemEditFieldLabel.Text = 'Floor Altitude (m)';
            app.FloorAltitudemEditField = uieditfield(app.TrajectoriesanalysesUIFigure, 'numeric');
            app.FloorAltitudemEditField.Limits = [0 Inf];
            app.FloorAltitudemEditField.Value = 0.0625;
            app.LateralPositionmEditFieldLabel = uilabel(app.TrajectoriesanalysesUIFigure);
            app.LateralPositionmEditFieldLabel.Text = 'Lateral Position (m)';
            app.LateralPositionmEditField = uieditfield(app.TrajectoriesanalysesUIFigure, 'numeric');
            app.LateralPositionmEditField.Limits = [0 Inf];
            app.LateralPositionmEditField.Value = 0.125;
        end
    end

    methods (Access = public)

        % Construct app
        function app = guiAT

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TrajectoriesanalysesUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrajectoriesanalysesUIFigure)
        end
    end
end